classdef proje4__app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SimulationControlSettingTab     matlab.ui.container.Tab
        Image5                          matlab.ui.control.Image
        SimulationTimeSolverSettingsGuidePanel  matlab.ui.container.Panel
        SaveParam_GUI_Button            matlab.ui.control.Button
        StartAndSolve_Simulink_Button   matlab.ui.control.Button
        SolverandTimingConfiguratorPanel  matlab.ui.container.Panel
        Panel_4                         matlab.ui.container.Panel
        SolverType_Switch               matlab.ui.control.RockerSwitch
        SolverTypeSwitchLabel           matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        SolverType_Fixed_Knob           matlab.ui.control.DiscreteKnob
        FixedKnobLabel                  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        SolverType_Variable_Knob        matlab.ui.control.DiscreteKnob
        VariableKnobLabel               matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        SimTime_StepSize_EditField      matlab.ui.control.NumericEditField
        StopTimeLabel_2                 matlab.ui.control.Label
        SimTime_StopTime_EditField      matlab.ui.control.NumericEditField
        StopTimeLabel                   matlab.ui.control.Label
        InitialConstantTab              matlab.ui.container.Tab
        InitialConditionsPanel          matlab.ui.container.Panel
        degsLabel_5                     matlab.ui.control.Label
        degsLabel_4                     matlab.ui.control.Label
        degsLabel_3                     matlab.ui.control.Label
        degsLabel_2                     matlab.ui.control.Label
        degsLabel                       matlab.ui.control.Label
        msLabel_3                       matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        degLabel_5                      matlab.ui.control.Label
        degLabel_4                      matlab.ui.control.Label
        degLabel_3                      matlab.ui.control.Label
        degLabel_2                      matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        InitAngleSpeed_beta_EditField   matlab.ui.control.NumericEditField
        AngleSpeed_betaEditFieldLabel   matlab.ui.control.Label
        InitAngleSpeed_alpha_EditField  matlab.ui.control.NumericEditField
        AngleSpeed_alphaEditFieldLabel  matlab.ui.control.Label
        InitAngleSpeed_psi_EditField    matlab.ui.control.NumericEditField
        AngleSpeed_psiLabel             matlab.ui.control.Label
        InitAngleSpeed_theta_EditField  matlab.ui.control.NumericEditField
        AngleSpeed_thetaEditFieldLabel  matlab.ui.control.Label
        InitAngleSpeed_phi_EditField    matlab.ui.control.NumericEditField
        AngleSpeed_phiEditFieldLabel    matlab.ui.control.Label
        InitSpeed_z_EditField           matlab.ui.control.NumericEditField
        Speed_zEditFieldLabel           matlab.ui.control.Label
        InitSpeed_y_EditField           matlab.ui.control.NumericEditField
        Speed_yEditFieldLabel           matlab.ui.control.Label
        InitSpeed_x_EditField           matlab.ui.control.NumericEditField
        Speed_xEditFieldLabel           matlab.ui.control.Label
        InitAngle_beta_EditField        matlab.ui.control.NumericEditField
        Angle_betaEditFieldLabel        matlab.ui.control.Label
        InitAngle_alpha_EditField       matlab.ui.control.NumericEditField
        Angle_alphaEditFieldLabel       matlab.ui.control.Label
        InitAngle_psi_EditField         matlab.ui.control.NumericEditField
        Angle_psiLabel                  matlab.ui.control.Label
        InitAngle_theta_EditField       matlab.ui.control.NumericEditField
        Angle_thetaEditFieldLabel       matlab.ui.control.Label
        InitAngle_phi_EditField         matlab.ui.control.NumericEditField
        Angle_phiEditFieldLabel         matlab.ui.control.Label
        InitPosition_z_EditField        matlab.ui.control.NumericEditField
        Position_zEditFieldLabel        matlab.ui.control.Label
        InitPosition_y_EditField        matlab.ui.control.NumericEditField
        Position_yEditFieldLabel        matlab.ui.control.Label
        InitPosition_x_EditField        matlab.ui.control.NumericEditField
        Position_xEditFieldLabel        matlab.ui.control.Label
        DronePendulumMechanicalConstantsPanel  matlab.ui.container.Panel
        mLabel_5                        matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        kgLabel_2                       matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        ConstLengthOfPendulum_EditField  matlab.ui.control.NumericEditField
        LengthofPendulumEditFieldLabel  matlab.ui.control.Label
        ConstMassOfPendulum_EditField   matlab.ui.control.NumericEditField
        MassofPendulumEditFieldLabel    matlab.ui.control.Label
        ConstLengthOfDroneArm_EditField  matlab.ui.control.NumericEditField
        LengthofDroneArmEditFieldLabel  matlab.ui.control.Label
        ConstMassOfDrone_EditField      matlab.ui.control.NumericEditField
        MassofDroneEditFieldLabel       matlab.ui.control.Label
        MotionStylesTab                 matlab.ui.container.Tab
        StyleFreeFallButton             matlab.ui.control.Button
        StyleBarrelRollTakeoffButton    matlab.ui.control.Button
        StylePendulumKickButton         matlab.ui.control.Button
        StyleVortexTakeoffButton        matlab.ui.control.Button
        StyleParabolicTakeoffButton     matlab.ui.control.Button
        StyleHoverButton                matlab.ui.control.Button
        DronePendulumDiagramTab         matlab.ui.container.Tab
        Image3                          matlab.ui.control.Image
        EulerAnglesTab                  matlab.ui.container.Tab
        eulerAngles_2                   matlab.ui.control.Image
        VisualizationSettingsPanel      matlab.ui.container.Panel
        Panel_6                         matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        Panel_5                         matlab.ui.container.Panel
        PapersButton                    matlab.ui.control.StateButton
        Panel                           matlab.ui.container.Panel
        UpdateIndicatorLamp             matlab.ui.control.Lamp
        UpdateIndicatorLampLabel        matlab.ui.control.Label
        ReportButton                    matlab.ui.control.Button
        Image4                          matlab.ui.control.Image
        WARNINGTextArea                 matlab.ui.control.TextArea
        WARNINGTextAreaLabel            matlab.ui.control.Label
        GraphicsSelectionScreenCheckBoxGroup  matlab.ui.container.ButtonGroup
        GraphCheckBox_AngleAngAccBeta   matlab.ui.control.CheckBox
        GraphCheckBox_AngleAccAlpha     matlab.ui.control.CheckBox
        GraphCheckBox_AngleAccPsi       matlab.ui.control.CheckBox
        GraphCheckBox_AngleAccTheta     matlab.ui.control.CheckBox
        GraphCheckBox_AngleAccPhi       matlab.ui.control.CheckBox
        GraphCheckBox_TransAcc_z        matlab.ui.control.CheckBox
        GraphCheckBox_TransAcc_y        matlab.ui.control.CheckBox
        GraphCheckBox_TransAcc_x        matlab.ui.control.CheckBox
        AngleandSpeedofthePendulum_Label  matlab.ui.control.Label
        EulerPositionandSpeedoftheQuadrotor_Label  matlab.ui.control.Label
        LateralPositionandSpeedoftheQuadrotor_Label  matlab.ui.control.Label
        GraphCheckBox_AngleSpeedBeta    matlab.ui.control.CheckBox
        GraphCheckBox_AngleBeta         matlab.ui.control.CheckBox
        GraphCheckBox_AngleSpeedAlpha   matlab.ui.control.CheckBox
        GraphCheckBox_AngleAlpha        matlab.ui.control.CheckBox
        GraphCheckBox_AngleSpeedPsi     matlab.ui.control.CheckBox
        GraphCheckBox_AnglePsi          matlab.ui.control.CheckBox
        GraphCheckBox_AngleSpeedTheta   matlab.ui.control.CheckBox
        GraphCheckBox_AngleTheta        matlab.ui.control.CheckBox
        GraphCheckBox_AngleSpeedPhi     matlab.ui.control.CheckBox
        GraphCheckBox_AnglePhi          matlab.ui.control.CheckBox
        GraphCheckBox_TransSpeed_z      matlab.ui.control.CheckBox
        GraphCheckBox_Pos_z             matlab.ui.control.CheckBox
        GraphCheckBox_TransSpeed_y      matlab.ui.control.CheckBox
        GraphCheckBox_Pos_y             matlab.ui.control.CheckBox
        GraphCheckBox_TransSpeed_x      matlab.ui.control.CheckBox
        GraphCheckBox_Pos_x             matlab.ui.control.CheckBox
        DroneMotorDynamicsManagerPanel  matlab.ui.container.Panel
        Image6                          matlab.ui.control.Image
        Motor4_ParametersPanel          matlab.ui.container.Panel
        ValueMotor4_T_Label             matlab.ui.control.Label
        ValueMotor4_F_Label             matlab.ui.control.Label
        Torque4_Slider                  matlab.ui.control.Slider
        Torque_NmLabel_4                matlab.ui.control.Label
        Force4_Slider                   matlab.ui.control.Slider
        Force_NLabel_4                  matlab.ui.control.Label
        Motor3_ParametersPanel          matlab.ui.container.Panel
        ValueMotor3_T_Label             matlab.ui.control.Label
        ValueMotor3_F_Label             matlab.ui.control.Label
        Torque3_Slider                  matlab.ui.control.Slider
        Torque_NmLabel_3                matlab.ui.control.Label
        Force3_Slider                   matlab.ui.control.Slider
        Force_NLabel_3                  matlab.ui.control.Label
        Motor2_ParametersPanel          matlab.ui.container.Panel
        ValueMotor2_T_Label             matlab.ui.control.Label
        ValueMotor2_F_Label             matlab.ui.control.Label
        Torque2_Slider                  matlab.ui.control.Slider
        Torque_NmLabel                  matlab.ui.control.Label
        Force2_Slider                   matlab.ui.control.Slider
        Force_NLabel_2                  matlab.ui.control.Label
        Motor1_ParametersPanel          matlab.ui.container.Panel
        ValueMotor1_T_Label             matlab.ui.control.Label
        ValueMotor1_F_Label             matlab.ui.control.Label
        Torque1_Slider                  matlab.ui.control.Slider
        Torque_NmLabel_2                matlab.ui.control.Label
        Force1_Slider                   matlab.ui.control.Slider
        Force_NLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Transfer simulation parameters and initial conditions from UI inputs to workspace

% -------------------------------------------------------------------------
%                            USER VARIABLES
% -------------------------------------------------------------------------

        %Motor Torque & Force Parameters
        Motor1_Torque_Nm; Motor1_Force_N;
        Motor2_Torque_Nm; Motor2_Force_N;
        Motor3_Torque_Nm; Motor3_Force_N;
        Motor4_Torque_Nm; Motor4_Force_N;

        %Constant Parameters
        Const_LengthOfPendulum_m;
        Const_MassOfPendulum_kg;
        Const_LengthOfDroneArm_m;
        Const_MassOfDrone_kg;

        %Initial Condition Parameters
        InitPosition_X_m;       InitSpeed_X_mPs;
        InitPosition_Y_m;       InitSpeed_Y_mPs;
        InitPosition_Z_m;       InitSpeed_Z_mPs;
        InitAngle_Phi_rad;      InitAngleSpeed_Phi_radPs;
        InitAngle_Theta_rad;    InitAngleSpeed_Theta_radPs;
        InitAngle_Psi_rad;      InitAngleSpeed_Psi_radPs;
        InitAngle_Alpha_rad;    InitAngleSpeed_Alpha_radPs;
        InitAngle_Beta_rad;     InitAngleSpeed_Beta_radPs;

        %Simulink Parameters
        SimStepSize_s;
        SimStopTime_s;
        SimSolverType;

        % The values received from the q matrix will be parsed into these variables.
        comingData_x_m, comingData_y_m, comingData_z_m;
        comingData_phi_rad, comingData_theta_rad, comingData_psi_rad;
        comingData_alpha_rad, comingData_beta_rad;

        % The values received from the q_dot matrix will be parsed into these variables.
        comingData_x_dot_mPs, comingData_y_dot_mPs, comingData_z_dot_mPs; 
        comingData_phi_dot_radPs, comingData_theta_dot_radPs, comingData_psi_dot_radPs;
        comingData_alpha_dot_radPs, comingData_beta_dot_radPs;
        
        % The values received from the q_dot matrix will be parsed into these variables.
        comingData_x_ddot_mPs2, comingData_y_ddot_mPs2, comingData_z_ddot_mPs2; 
        comingData_phi_ddot_radPs2, comingData_theta_ddot_radPs2, comingData_psi_ddot_radPs2;
        comingData_alpha_ddot_radPs2, comingData_beta_ddot_radPs2; 

        % The values received from time matrix and will be saved into the time
        comingData_time_s;

        % Stores the number of selected checkboxes
        numCheckBox;

        % The following plots will be displayed according to the user's selection. 
        % Each plot represents a specific state variable's evolution over time.
        plotHandle;

    end

% -------------------------------------------------------------------------
%                            USER FUNCTIONS
% -------------------------------------------------------------------------


    methods (Access = private)
                % This function updates the plot's title and y-axis label based on the 
        % selection of the time/relative graph type and the selected checkbox.
        % It checks the value of `app.basedOn_TimeOrRelative` to determine whether 
        % the graph is based on time or relative data, and adjusts the title accordingly.
        % It also updates the y-axis label depending on the selected checkbox (1-6).
        function updatePlotTitle(app,titleState)
             
            title(app.UIAxes, "Time-Dependent Graph");
            xlabel(app.UIAxes, "Time (sec)");
                
                    if titleState == 1
                           
                            if app.numCheckBox == 1
                                ylabel(app.UIAxes, "X-Position(m)");
            
                            elseif app.numCheckBox  == 2
                                ylabel(app.UIAxes, "Y-Position(m)");
                            
                            elseif app.numCheckBox  == 3
                                ylabel(app.UIAxes, "Z-Position(m)");
            
                            elseif app.numCheckBox  == 4
                                ylabel(app.UIAxes, "Φ(roll)-Angle(Rad)");

                            elseif app.numCheckBox  == 5
                                ylabel(app.UIAxes, "Θ(pitch)-Angle(Rad)");

                            elseif app.numCheckBox  == 6
                                ylabel(app.UIAxes, "Ψ(yaw)-Angle(Rad)");

                            elseif app.numCheckBox  == 7
                                ylabel(app.UIAxes, "α-Angle(Rad)");

                            elseif app.numCheckBox  == 8
                                ylabel(app.UIAxes, "β-Angle(Rad)");

                            elseif app.numCheckBox  == 9
                                ylabel(app.UIAxes, "X-Speed(mPs)"); 
                            
                            elseif app.numCheckBox  == 10
                                ylabel(app.UIAxes, "Y-Speed(mPs)"); 
            
                            elseif app.numCheckBox  == 11
                                ylabel(app.UIAxes, "Z-Speed(mPs)"); 

                            elseif app.numCheckBox  == 12
                                ylabel(app.UIAxes, "Φ(roll)-AngleSpeed(radPs)");

                            elseif app.numCheckBox  == 13
                                ylabel(app.UIAxes, "Θ(pitch)-AngleSpeed(radPs)");
                                
                            elseif app.numCheckBox  == 14
                                ylabel(app.UIAxes, "Ψ(yaw)-AngleSpeed(radPs)");

                            elseif app.numCheckBox  == 15
                                ylabel(app.UIAxes, "α-AngleSpeed(radPs)");

                            elseif app.numCheckBox  == 16
                                ylabel(app.UIAxes, "β-AngleSpeed(radPs)");

                            elseif app.numCheckBox  == 17
                                ylabel(app.UIAxes, "X-Acceleration(mPs2)");

                            elseif app.numCheckBox  == 18
                                ylabel(app.UIAxes, "Y-Acceleration(mPs2)");

                            elseif app.numCheckBox  == 19
                                ylabel(app.UIAxes, "Z-Acceleration(mPs2)");

                            elseif app.numCheckBox  == 20
                                ylabel(app.UIAxes, "Φ(roll)-Acceleration(radPs2)");

                            elseif app.numCheckBox  == 21
                                ylabel(app.UIAxes, "Θ(pitch)-Acceleration(radPs2)");

                            elseif app.numCheckBox  == 22
                                ylabel(app.UIAxes, "Ψ(yaw)-Acceleration(radPs2)");

                            elseif app.numCheckBox  == 23
                                ylabel(app.UIAxes, "α-Acceleration(radPs2)");

                            elseif app.numCheckBox  == 24
                                ylabel(app.UIAxes, "β-Acceleration(radPs2)");
                            end
                                                         
                    else
                        ylabel(app.UIAxes, "-Y?-");
                    end

            end
        
        function showSelectedPlot(app,whichPlot,plotState)
           
            if plotState == 0
               app.plotHandle(whichPlot).Visible = 'off';

            else
                app.plotHandle(whichPlot).Visible = 'on';
            end

        end

end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveParam_GUI_Button
        function SaveParam_GUI_ButtonPushed(app, event)
            
    SIP_SimModel = "proje4__SIP_DynSys_Model_V2_0";
    load_system(SIP_SimModel);       % Load the model but do not run it, load it background
                                         %to set simulink configuration parameters   

    %-----------SYSTEM CONSTANT------------%
    % Get the mass & lengths of the system
    app.Const_MassOfDrone_kg     = app.ConstMassOfDrone_EditField.Value;
    app.Const_MassOfPendulum_kg  = app.ConstMassOfPendulum_EditField.Value;
    app.Const_LengthOfDroneArm_m = app.ConstLengthOfDroneArm_EditField.Value;
    app.Const_LengthOfPendulum_m = app.ConstLengthOfPendulum_EditField.Value;

    Const_Array = [app.Const_MassOfDrone_kg,app.Const_MassOfPendulum_kg,...
                   app.Const_LengthOfDroneArm_m,app.Const_LengthOfPendulum_m];
    assignin('base','systemConstant',Const_Array);

    %-----------MOTOR TORQUE & FORCE------------%
    % Get the input forces of the system 
    app.Motor1_Force_N = app.Force1_Slider.Value;
    app.Motor2_Force_N = app.Force2_Slider.Value;
    app.Motor3_Force_N = app.Force3_Slider.Value;
    app.Motor4_Force_N = app.Force4_Slider.Value;
    
    Force_Array = [app.Motor1_Force_N, app.Motor2_Force_N,...
                   app.Motor3_Force_N, app.Motor4_Force_N];
    assignin('base','motorForces',Force_Array);

    % Get the input torques of the system
    app.Motor1_Torque_Nm = app.Torque1_Slider.Value;
    app.Motor2_Torque_Nm = app.Torque2_Slider.Value;
    app.Motor3_Torque_Nm = app.Torque3_Slider.Value;
    app.Motor4_Torque_Nm = app.Torque4_Slider.Value;

    Torque_Array = [app.Motor1_Torque_Nm,app.Motor2_Torque_Nm,...
                    app.Motor3_Torque_Nm,app.Motor4_Torque_Nm];
    assignin('base','motorTorques',Torque_Array);


    %-----------SYSTEM INITIAL CONDITION-------------%
    % Get the state [x,y,z,phi,theta,psi,alpha,beta] of the system 
    app.InitPosition_X_m = app.InitPosition_x_EditField.Value;
    app.InitPosition_Y_m = app.InitPosition_y_EditField.Value;
    app.InitPosition_Z_m = app.InitPosition_z_EditField.Value;
    app.InitAngle_Phi_rad   = deg2rad(app.InitAngle_phi_EditField.Value);
    app.InitAngle_Theta_rad = deg2rad(app.InitAngle_theta_EditField.Value);
    app.InitAngle_Psi_rad   = deg2rad(app.InitAngle_psi_EditField.Value);
    app.InitAngle_Alpha_rad = deg2rad(app.InitAngle_alpha_EditField.Value);
    app.InitAngle_Beta_rad  = deg2rad(app.InitAngle_beta_EditField.Value);
    
    Init_q_Array = [app.InitPosition_X_m; app.InitPosition_Y_m; app.InitPosition_Z_m;...
                    app.InitAngle_Phi_rad; app.InitAngle_Theta_rad; app.InitAngle_Psi_rad;...
                    app.InitAngle_Alpha_rad; app.InitAngle_Beta_rad];
    assignin('base','q_InitCond',Init_q_Array);

    % Get the state (d/dt)*[x,y,z,phi,theta,psi,alpha,beta] of the system 
    app.InitSpeed_X_mPs = app.InitSpeed_x_EditField.Value;
    app.InitSpeed_Y_mPs = app.InitSpeed_y_EditField.Value;
    app.InitSpeed_Z_mPs = app.InitSpeed_z_EditField.Value;
    app.InitAngleSpeed_Phi_radPs   = deg2rad(app.InitAngleSpeed_phi_EditField.Value);
    app.InitAngleSpeed_Theta_radPs = deg2rad(app.InitAngleSpeed_theta_EditField.Value);
    app.InitAngleSpeed_Psi_radPs   = deg2rad(app.InitAngleSpeed_psi_EditField.Value);
    app.InitAngleSpeed_Alpha_radPs = deg2rad(app.InitAngleSpeed_alpha_EditField.Value);
    app.InitAngleSpeed_Beta_radPs  = deg2rad(app.InitAngleSpeed_beta_EditField.Value);

    Init_qdot_Array = [app.InitSpeed_X_mPs; app.InitSpeed_Y_mPs; app.InitSpeed_Z_mPs;...
                       app.InitAngleSpeed_Phi_radPs; app.InitAngleSpeed_Theta_radPs; app.InitAngleSpeed_Psi_radPs;...
                       app.InitAngleSpeed_Alpha_radPs; app.InitAngleSpeed_Beta_radPs];
    assignin('base','q_dot_InitCond',Init_qdot_Array);


    %-----------SYSTEM TIME & INPUT SETTING-------------%
    % Get the Simulink time information
    app.SimStepSize_s = app.SimTime_StepSize_EditField.Value;
    app.SimStopTime_s = app.SimTime_StopTime_EditField.Value;
             
    set_param(SIP_SimModel,"StopTime",num2str(app.SimStopTime_s));
    set_param(SIP_SimModel,"FixedStep",num2str(app.SimStepSize_s));

    % Get the Simulink solver and type information
    app.SimSolverType = app.SolverType_Switch.Value;        %0(default Variable Type), 1(Fixed Type)
    
   if app.SimSolverType == "Fixed"

        set_param(SIP_SimModel,'SolverType','Fixed-step');

            if app.SolverType_Fixed_Knob.Value == "auto"
               set_param(SIP_SimModel,"SolverName","FixedStepAuto");

                elseif app.SolverType_Fixed_Knob.Value == "ode8"
                    set_param(SIP_SimModel,"SolverName","ode8");

                elseif app.SolverType_Fixed_Knob.Value == "ode5"
                    set_param(SIP_SimModel,"SolverName","ode5");


                elseif app.SolverType_Fixed_Knob.Value == "ode4"
                    set_param(SIP_SimModel,"SolverName","ode4");


                elseif app.SolverType_Fixed_Knob.Value == "ode3"
                    set_param(SIP_SimModel,"SolverName","ode3");


                elseif app.SolverType_Fixed_Knob.Value == "ode2"
                    set_param(SIP_SimModel,"SolverName","ode2");
            end
   end


   if app.SimSolverType == "Variable"

        set_param(SIP_SimModel,"SolverType","Variable-step");

           if app.SolverType_Variable_Knob.Value == "auto"
               set_param(SIP_SimModel,"SolverName","VariableStepAuto");

                elseif app.SolverType_Variable_Knob.Value == "ode45"
                    set_param(SIP_SimModel,"SolverName","ode45");

                elseif app.SolverType_Variable_Knob.Value == "ode113"
                    set_param(SIP_SimModel,"SolverName","ode113");

                elseif app.SolverType_Variable_Knob.Value == "ode23"
                    set_param(SIP_SimModel,"SolverName","ode23");
           end
   end

    % Turn the lamp green so that the user knows the changes have been saved
    app.UpdateIndicatorLamp.Color = [0.00,1.00,0.00];
        end

        % Value changing function: Force1_Slider
        function Force1_SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ValueMotor1_F_Label.Text = num2str(changingValue);  
            % Set the lamp color to red to inform the user that unsaved changes exist
            app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];            
        end

        % Value changing function: Torque1_Slider
        function Torque1_SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ValueMotor1_T_Label.Text = num2str(changingValue); 
            % Set the lamp color to red to inform the user that unsaved changes exist
            app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];            
        end

        % Value changing function: Force2_Slider
        function Force2_SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ValueMotor2_F_Label.Text = num2str(changingValue);    
            % Set the lamp color to red to inform the user that unsaved changes exist
            app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];            
        end

        % Value changing function: Torque2_Slider
        function Torque2_SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ValueMotor2_T_Label.Text = num2str(changingValue);
            % Set the lamp color to red to inform the user that unsaved changes exist
            app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];            
        end

        % Value changing function: Force3_Slider
        function Force3_SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ValueMotor3_F_Label.Text = num2str(changingValue); 
            % Set the lamp color to red to inform the user that unsaved changes exist
            app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];            
        end

        % Value changing function: Torque3_Slider
        function Torque3_SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ValueMotor3_T_Label.Text = num2str(changingValue);         
            % Set the lamp color to red to inform the user that unsaved changes exist
            app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];            
        end

        % Value changing function: Force4_Slider
        function Force4_SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ValueMotor4_F_Label.Text = num2str(changingValue);
            % Set the lamp color to red to inform the user that unsaved changes exist
            app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];            
        end

        % Value changing function: Torque4_Slider
        function Torque4_SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ValueMotor4_T_Label.Text = num2str(changingValue);
            % Set the lamp color to red to inform the user that unsaved changes exist
            app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];
        end

        % Button pushed function: StartAndSolve_Simulink_Button
        function StartAndSolve_Simulink_ButtonPushed(app, event)
            SIP_SimModel = "proje4__SIP_DynSys_Model_V2_0";
            open_system(SIP_SimModel);             %Open simulink but not run it
            SIP_Sim_v1_out = sim(SIP_SimModel);    %Run it and save output struct into the 'Crane_Sim_v1_out'
            
            % A copy of the outputs is also assigned to the workspace 
            % in case they are needed by other functions or for manual inspection.
            assignin('base','SIP_Sim_v1_out',SIP_Sim_v1_out);
            
            % Parsing the simulation outputs.( q States ) 
            app.comingData_x_m = SIP_Sim_v1_out.out_q(:,1);
            app.comingData_y_m = SIP_Sim_v1_out.out_q(:,2);
            app.comingData_z_m = SIP_Sim_v1_out.out_q(:,3);
            app.comingData_phi_rad   = SIP_Sim_v1_out.out_q(:,4);
            app.comingData_theta_rad = SIP_Sim_v1_out.out_q(:,5);
            app.comingData_psi_rad   = SIP_Sim_v1_out.out_q(:,6);
            app.comingData_alpha_rad = SIP_Sim_v1_out.out_q(:,7);
            app.comingData_beta_rad  = SIP_Sim_v1_out.out_q(:,8);

            % Parsing the simulation outputs.( q_dot States ) 
            app.comingData_x_dot_mPs = SIP_Sim_v1_out.out_q_dot(:,1);
            app.comingData_y_dot_mPs = SIP_Sim_v1_out.out_q_dot(:,2);
            app.comingData_z_dot_mPs = SIP_Sim_v1_out.out_q_dot(:,3);
            app.comingData_phi_dot_radPs   = SIP_Sim_v1_out.out_q_dot(:,4);
            app.comingData_theta_dot_radPs = SIP_Sim_v1_out.out_q_dot(:,5);
            app.comingData_psi_dot_radPs   = SIP_Sim_v1_out.out_q_dot(:,6);
            app.comingData_alpha_dot_radPs = SIP_Sim_v1_out.out_q_dot(:,7);
            app.comingData_beta_dot_radPs  = SIP_Sim_v1_out.out_q_dot(:,8);

            % Parsing the simulation outputs.( q_ddot States )
            app.comingData_x_ddot_mPs2 = SIP_Sim_v1_out.out_q_ddot(:,1);
            app.comingData_y_ddot_mPs2 = SIP_Sim_v1_out.out_q_ddot(:,2);
            app.comingData_z_ddot_mPs2 = SIP_Sim_v1_out.out_q_ddot(:,3);
            app.comingData_phi_ddot_radPs2   = SIP_Sim_v1_out.out_q_ddot(:,4);
            app.comingData_theta_ddot_radPs2 = SIP_Sim_v1_out.out_q_ddot(:,5);
            app.comingData_psi_ddot_radPs2   = SIP_Sim_v1_out.out_q_ddot(:,6);
            app.comingData_alpha_ddot_radPs2 = SIP_Sim_v1_out.out_q_ddot(:,7);
            app.comingData_beta_ddot_radPs2  = SIP_Sim_v1_out.out_q_ddot(:,8);
    

            app.comingData_time_s = SIP_Sim_v1_out.tout;

             % Plot the different data series over time on the UIAxes.
             % Each data set represents a specific state or derivative of the system.
             % 'Visible', 'off' ensures the plot is initially hidden until user selection.
             app.plotHandle = plot(app.UIAxes,app.comingData_time_s, app.comingData_x_m, ... 
                                              app.comingData_time_s, app.comingData_y_m, ...
                                              app.comingData_time_s, app.comingData_z_m, ...
                                              app.comingData_time_s, app.comingData_phi_rad, ...
                                              app.comingData_time_s, app.comingData_theta_rad, ...
                                              app.comingData_time_s, app.comingData_psi_rad, ...
                                              app.comingData_time_s, app.comingData_alpha_rad, ...
                                              app.comingData_time_s, app.comingData_beta_rad, ...
                                              app.comingData_time_s, app.comingData_x_dot_mPs, ...
                                              app.comingData_time_s, app.comingData_y_dot_mPs, ...
                                              app.comingData_time_s, app.comingData_z_dot_mPs, ...
                                              app.comingData_time_s, app.comingData_phi_dot_radPs, ...
                                              app.comingData_time_s, app.comingData_theta_dot_radPs, ...
                                              app.comingData_time_s, app.comingData_psi_dot_radPs, ...
                                              app.comingData_time_s, app.comingData_alpha_dot_radPs, ...
                                              app.comingData_time_s, app.comingData_beta_dot_radPs, ...
                                              app.comingData_time_s, app.comingData_x_ddot_mPs2, ...
                                              app.comingData_time_s, app.comingData_y_ddot_mPs2, ...
                                              app.comingData_time_s, app.comingData_z_ddot_mPs2, ...
                                              app.comingData_time_s, app.comingData_phi_ddot_radPs2, ...
                                              app.comingData_time_s, app.comingData_theta_ddot_radPs2, ...
                                              app.comingData_time_s, app.comingData_psi_ddot_radPs2, ...
                                              app.comingData_time_s, app.comingData_alpha_ddot_radPs2, ...
                                              app.comingData_time_s, app.comingData_beta_ddot_radPs2, ...
                                              'Visible','off');
                                                       

        end

        % Value changed function: GraphCheckBox_Pos_x
        function GraphCheckBox_Pos_xValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_Pos_x.Value;
            app.numCheckBox = 1;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox); 
        end

        % Value changed function: GraphCheckBox_Pos_y
        function GraphCheckBox_Pos_yValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_Pos_y.Value;
            app.numCheckBox = 2;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);   
        end

        % Value changed function: GraphCheckBox_Pos_z
        function GraphCheckBox_Pos_zValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_Pos_z.Value;
            app.numCheckBox = 3;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);            
        end

        % Value changed function: GraphCheckBox_AnglePhi
        function GraphCheckBox_AnglePhiValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_AnglePhi.Value;
            app.numCheckBox = 4;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);
        end

        % Value changed function: GraphCheckBox_AngleTheta
        function GraphCheckBox_AngleThetaValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_AngleTheta.Value;
            app.numCheckBox = 5;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);
        end

        % Value changed function: GraphCheckBox_AnglePsi
        function GraphCheckBox_AnglePsiValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_AnglePsi.Value;
            app.numCheckBox = 6;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);          
        end

        % Value changed function: GraphCheckBox_AngleAlpha
        function GraphCheckBox_AngleAlphaValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_AngleAlpha.Value;
            app.numCheckBox = 7;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);
        end

        % Value changed function: GraphCheckBox_AngleBeta
        function GraphCheckBox_AngleBetaValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_AngleBeta.Value;
            app.numCheckBox = 8;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);
        end

        % Value changed function: GraphCheckBox_TransSpeed_x
        function GraphCheckBox_TransSpeed_xValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_TransSpeed_x.Value;
            app.numCheckBox = 9;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);     
        end

        % Value changed function: GraphCheckBox_TransSpeed_y
        function GraphCheckBox_TransSpeed_yValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_TransSpeed_y.Value;
            app.numCheckBox = 10;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);          
        end

        % Value changed function: GraphCheckBox_TransSpeed_z
        function GraphCheckBox_TransSpeed_zValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_TransSpeed_z.Value;
            app.numCheckBox = 11;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);    
        end

        % Value changed function: GraphCheckBox_AngleSpeedPhi
        function GraphCheckBox_AngleSpeedPhiValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_AngleSpeedPhi.Value;
            app.numCheckBox = 12;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);
        end

        % Value changed function: GraphCheckBox_AngleSpeedTheta
        function GraphCheckBox_AngleSpeedThetaValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_AngleSpeedTheta.Value;
            app.numCheckBox = 13;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);
        end

        % Value changed function: GraphCheckBox_AngleSpeedPsi
        function GraphCheckBox_AngleSpeedPsiValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_AngleSpeedPsi.Value;
            app.numCheckBox = 14;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);
        end

        % Value changed function: GraphCheckBox_AngleSpeedAlpha
        function GraphCheckBox_AngleSpeedAlphaValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_AngleSpeedAlpha.Value;
            app.numCheckBox = 15;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);
        end

        % Value changed function: GraphCheckBox_AngleSpeedBeta
        function GraphCheckBox_AngleSpeedBetaValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_AngleSpeedBeta.Value;
            app.numCheckBox = 16;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);
        end

        % Value changed function: GraphCheckBox_TransAcc_x
        function GraphCheckBox_TransAcc_xValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_TransAcc_x.Value;
            app.numCheckBox = 17;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);
        end

        % Value changed function: GraphCheckBox_TransAcc_y
        function GraphCheckBox_TransAcc_yValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_TransAcc_y.Value;
            app.numCheckBox = 18;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);        
        end

        % Value changed function: GraphCheckBox_TransAcc_z
        function GraphCheckBox_TransAcc_zValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_TransAcc_z.Value;
            app.numCheckBox = 19;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);         
        end

        % Value changed function: GraphCheckBox_AngleAccPhi
        function GraphCheckBox_AngleAccPhiValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_AngleAccPhi.Value;
            app.numCheckBox = 20;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);
        end

        % Value changed function: GraphCheckBox_AngleAccTheta
        function GraphCheckBox_AngleAccThetaValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_AngleAccTheta.Value;
            app.numCheckBox = 21;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);
        end

        % Value changed function: GraphCheckBox_AngleAccPsi
        function GraphCheckBox_AngleAccPsiValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_AngleAccPsi.Value;
            app.numCheckBox = 22;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);
        end

        % Value changed function: GraphCheckBox_AngleAccAlpha
        function GraphCheckBox_AngleAccAlphaValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_AngleAccAlpha.Value;
            app.numCheckBox = 23;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);
        end

        % Value changed function: GraphCheckBox_AngleAngAccBeta
        function GraphCheckBox_AngleAccBetaValueChanged(app, event)
            stateCheckBox = app.GraphCheckBox_AngleAngAccBeta.Value;
            app.numCheckBox = 24;
            updatePlotTitle(app,stateCheckBox);
            showSelectedPlot(app,app.numCheckBox,stateCheckBox);
        end

        % Button down function: InitialConditionsPanel
        function InitialConditionsPanelButtonDown(app, event)
            % Set the lamp color to red to inform the user that unsaved changes exist
            app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];
        end

        % Button down function: DronePendulumMechanicalConstantsPanel
        function DronePendulumMechanicalConstantsPanelButtonDown(app, event)
            % Set the lamp color to red to inform the user that unsaved changes exist
            app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];
        end

        % Value changed function: SolverType_Variable_Knob
        function SolverType_Variable_KnobValueChanged(app, event)
            % Set the lamp color to red to inform the user that unsaved changes exist
            app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];
        end

        % Value changed function: SolverType_Fixed_Knob
        function SolverType_Fixed_KnobValueChanged(app, event)
            % Set the lamp color to red to inform the user that unsaved changes exist
            app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];
        end

        % Value changed function: SolverType_Switch
        function SolverType_SwitchValueChanged(app, event)
            % Set the lamp color to red to inform the user that unsaved changes exist
            app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];
        end

        % Button down function: SolverandTimingConfiguratorPanel
        function SolverandTimingConfiguratorPanelButtonDown(app, event)
            % Set the lamp color to red to inform the user that unsaved changes exist
            app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];
        end

        % Button pushed function: StyleHoverButton
        function StyleHoverButtonPushed(app, event)
    %-----------SYSTEM CONSTANT------------%
    % Get the mass & lengths of the system
    app.ConstMassOfDrone_EditField.Value       = 1.5;
    app.ConstMassOfPendulum_EditField.Value    = 0.65; 
    app.ConstLengthOfDroneArm_EditField.Value  = 2;
    app.ConstLengthOfPendulum_EditField.Value  = 1.2;

    %-----------MOTOR TORQUE & FORCE------------%
    % Get the input forces of the system 
    app.Force1_Slider.Value = 5.2728;
    app.Force2_Slider.Value = 5.2728;
    app.Force3_Slider.Value = 5.2728;
    app.Force4_Slider.Value = 5.2728;
    app.ValueMotor1_F_Label.Text = num2str(app.Force1_Slider.Value);
    app.ValueMotor2_F_Label.Text = num2str(app.Force2_Slider.Value);
    app.ValueMotor3_F_Label.Text = num2str(app.Force3_Slider.Value);
    app.ValueMotor4_F_Label.Text = num2str(app.Force4_Slider.Value);

    % Get the input torques of the system
    app.Torque1_Slider.Value = 5;
    app.Torque2_Slider.Value = 5;
    app.Torque3_Slider.Value = 5;
    app.Torque4_Slider.Value = 5;
    app.ValueMotor1_T_Label.Text = num2str(app.Torque1_Slider.Value);
    app.ValueMotor2_T_Label.Text = num2str(app.Torque2_Slider.Value);
    app.ValueMotor3_T_Label.Text = num2str(app.Torque3_Slider.Value);
    app.ValueMotor4_T_Label.Text = num2str(app.Torque4_Slider.Value);

    %-----------SYSTEM INITIAL CONDITION-------------%
    % Get the state [x,y,z,phi,theta,psi,alpha,beta] of the system 
    app.InitPosition_x_EditField.Value   = 0;
    app.InitPosition_y_EditField.Value   = 0;
    app.InitPosition_z_EditField.Value   = 1;
    app.InitAngle_phi_EditField.Value    = 0;
    app.InitAngle_theta_EditField.Value  = 0;
    app.InitAngle_psi_EditField.Value    = 0;
    app.InitAngle_alpha_EditField.Value  = 0;
    app.InitAngle_beta_EditField.Value   = 0; 
   
    % Get the state (d/dt)*[x,y,z,phi,theta,psi,alpha,beta] of the system 
    app.InitSpeed_x_EditField.Value  = 0;
    app.InitSpeed_y_EditField.Value  = 0;
    app.InitSpeed_z_EditField.Value  = 0;
    app.InitAngleSpeed_phi_EditField.Value     = 0;
    app.InitAngleSpeed_theta_EditField.Value   = 0;
    app.InitAngleSpeed_psi_EditField.Value     = 0;
    app.InitAngleSpeed_alpha_EditField.Value   = 0;
    app.InitAngleSpeed_beta_EditField.Value    = 0;

    %-----------SYSTEM TIME & INPUT SETTING-------------%
    % Get the Simulink time information
    app.SimTime_StepSize_EditField.Value  = 0.001;
    app.SimTime_StopTime_EditField.Value  = 10;

     
    % Set the lamp color to red to inform the user that unsaved changes exist
    app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];
        end

        % Button pushed function: StyleParabolicTakeoffButton
        function StyleParabolicTakeoffButtonPushed(app, event)
    %-----------SYSTEM CONSTANT------------%
    % Get the mass & lengths of the system
    app.ConstMassOfDrone_EditField.Value       = 1.5;
    app.ConstMassOfPendulum_EditField.Value    = 0.65; 
    app.ConstLengthOfDroneArm_EditField.Value  = 2;
    app.ConstLengthOfPendulum_EditField.Value  = 1.2;

    %-----------MOTOR TORQUE & FORCE------------%
    % Get the input forces of the system 
    app.Force1_Slider.Value = 7;
    app.Force2_Slider.Value = 7;
    app.Force3_Slider.Value = 7;
    app.Force4_Slider.Value = 7;
    app.ValueMotor1_F_Label.Text = num2str(app.Force1_Slider.Value);
    app.ValueMotor2_F_Label.Text = num2str(app.Force2_Slider.Value);
    app.ValueMotor3_F_Label.Text = num2str(app.Force3_Slider.Value);
    app.ValueMotor4_F_Label.Text = num2str(app.Force4_Slider.Value);

    % Get the input torques of the system
    app.Torque1_Slider.Value = 5;
    app.Torque2_Slider.Value = 5;
    app.Torque3_Slider.Value = 5;
    app.Torque4_Slider.Value = 5;
    app.ValueMotor1_T_Label.Text = num2str(app.Torque1_Slider.Value);
    app.ValueMotor2_T_Label.Text = num2str(app.Torque2_Slider.Value);
    app.ValueMotor3_T_Label.Text = num2str(app.Torque3_Slider.Value);
    app.ValueMotor4_T_Label.Text = num2str(app.Torque4_Slider.Value);

    %-----------SYSTEM INITIAL CONDITION-------------%
    % Get the state [x,y,z,phi,theta,psi,alpha,beta] of the system 
    app.InitPosition_x_EditField.Value   = 0;
    app.InitPosition_y_EditField.Value   = 0;
    app.InitPosition_z_EditField.Value   = 0.3;
    app.InitAngle_phi_EditField.Value    = 0;
    app.InitAngle_theta_EditField.Value  = 0;
    app.InitAngle_psi_EditField.Value    = 0;
    app.InitAngle_alpha_EditField.Value  = 0;
    app.InitAngle_beta_EditField.Value   = 0; 
   
    % Get the state (d/dt)*[x,y,z,phi,theta,psi,alpha,beta] of the system 
    app.InitSpeed_x_EditField.Value  = 5;
    app.InitSpeed_y_EditField.Value  = 0;
    app.InitSpeed_z_EditField.Value  = 0;
    app.InitAngleSpeed_phi_EditField.Value     = 0;
    app.InitAngleSpeed_theta_EditField.Value   = 0;
    app.InitAngleSpeed_psi_EditField.Value     = 0;
    app.InitAngleSpeed_alpha_EditField.Value   = 0;
    app.InitAngleSpeed_beta_EditField.Value    = 0;

    %-----------SYSTEM TIME & INPUT SETTING-------------%
    % Get the Simulink time information
    app.SimTime_StepSize_EditField.Value  = 0.001;
    app.SimTime_StopTime_EditField.Value  = 10;

     
    % Set the lamp color to red to inform the user that unsaved changes exist
    app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];
        end

        % Button pushed function: StyleVortexTakeoffButton
        function StyleVortexTakeoffButtonPushed(app, event)
    %-----------SYSTEM CONSTANT------------%
    % Get the mass & lengths of the system
    app.ConstMassOfDrone_EditField.Value       = 1.5;
    app.ConstMassOfPendulum_EditField.Value    = 0.65; 
    app.ConstLengthOfDroneArm_EditField.Value  = 2;
    app.ConstLengthOfPendulum_EditField.Value  = 1.2;

    %-----------MOTOR TORQUE & FORCE------------%
    % Get the input forces of the system 
    app.Force1_Slider.Value = 7;
    app.Force2_Slider.Value = 7;
    app.Force3_Slider.Value = 7;
    app.Force4_Slider.Value = 7;
    app.ValueMotor1_F_Label.Text = num2str(app.Force1_Slider.Value);
    app.ValueMotor2_F_Label.Text = num2str(app.Force2_Slider.Value);
    app.ValueMotor3_F_Label.Text = num2str(app.Force3_Slider.Value);
    app.ValueMotor4_F_Label.Text = num2str(app.Force4_Slider.Value);

    % Get the input torques of the system
    app.Torque1_Slider.Value = 5;
    app.Torque2_Slider.Value = 5;
    app.Torque3_Slider.Value = 5;
    app.Torque4_Slider.Value = 5;
    app.ValueMotor1_T_Label.Text = num2str(app.Torque1_Slider.Value);
    app.ValueMotor2_T_Label.Text = num2str(app.Torque2_Slider.Value);
    app.ValueMotor3_T_Label.Text = num2str(app.Torque3_Slider.Value);
    app.ValueMotor4_T_Label.Text = num2str(app.Torque4_Slider.Value);

    %-----------SYSTEM INITIAL CONDITION-------------%
    % Get the state [x,y,z,phi,theta,psi,alpha,beta] of the system 
    app.InitPosition_x_EditField.Value   = 0;
    app.InitPosition_y_EditField.Value   = 0;
    app.InitPosition_z_EditField.Value   = 0.3;
    app.InitAngle_phi_EditField.Value    = 0;
    app.InitAngle_theta_EditField.Value  = 0;
    app.InitAngle_psi_EditField.Value    = 0;
    app.InitAngle_alpha_EditField.Value  = 0;
    app.InitAngle_beta_EditField.Value   = 0; 
   
    % Get the state (d/dt)*[x,y,z,phi,theta,psi,alpha,beta] of the system 
    app.InitSpeed_x_EditField.Value  = 5;
    app.InitSpeed_y_EditField.Value  = 0;
    app.InitSpeed_z_EditField.Value  = 0;
    app.InitAngleSpeed_phi_EditField.Value     = 0;
    app.InitAngleSpeed_theta_EditField.Value   = 0;
    app.InitAngleSpeed_psi_EditField.Value     = 150;
    app.InitAngleSpeed_alpha_EditField.Value   = 0;
    app.InitAngleSpeed_beta_EditField.Value    = 0;

    %-----------SYSTEM TIME & INPUT SETTING-------------%
    % Get the Simulink time information
    app.SimTime_StepSize_EditField.Value  = 0.001;
    app.SimTime_StopTime_EditField.Value  = 10;

     
    % Set the lamp color to red to inform the user that unsaved changes exist
    app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];  
        end

        % Button pushed function: StylePendulumKickButton
        function StylePendulumKickButtonPushed(app, event)
    %-----------SYSTEM CONSTANT------------%
    % Get the mass & lengths of the system
    app.ConstMassOfDrone_EditField.Value       = 1.5;
    app.ConstMassOfPendulum_EditField.Value    = 0.65; 
    app.ConstLengthOfDroneArm_EditField.Value  = 2;
    app.ConstLengthOfPendulum_EditField.Value  = 1.2;

    %-----------MOTOR TORQUE & FORCE------------%
    % Get the input forces of the system 
    app.Force1_Slider.Value = 7;
    app.Force2_Slider.Value = 7;
    app.Force3_Slider.Value = 7;
    app.Force4_Slider.Value = 7;
    app.ValueMotor1_F_Label.Text = num2str(app.Force1_Slider.Value);
    app.ValueMotor2_F_Label.Text = num2str(app.Force2_Slider.Value);
    app.ValueMotor3_F_Label.Text = num2str(app.Force3_Slider.Value);
    app.ValueMotor4_F_Label.Text = num2str(app.Force4_Slider.Value);

    % Get the input torques of the system
    app.Torque1_Slider.Value = 5;
    app.Torque2_Slider.Value = 5;
    app.Torque3_Slider.Value = 5;
    app.Torque4_Slider.Value = 5;
    app.ValueMotor1_T_Label.Text = num2str(app.Torque1_Slider.Value);
    app.ValueMotor2_T_Label.Text = num2str(app.Torque2_Slider.Value);
    app.ValueMotor3_T_Label.Text = num2str(app.Torque3_Slider.Value);
    app.ValueMotor4_T_Label.Text = num2str(app.Torque4_Slider.Value);

    %-----------SYSTEM INITIAL CONDITION-------------%
    % Get the state [x,y,z,phi,theta,psi,alpha,beta] of the system 
    app.InitPosition_x_EditField.Value   = 0;
    app.InitPosition_y_EditField.Value   = 0;
    app.InitPosition_z_EditField.Value   = 2;
    app.InitAngle_phi_EditField.Value    = 0;
    app.InitAngle_theta_EditField.Value  = 0;
    app.InitAngle_psi_EditField.Value    = 0;
    app.InitAngle_alpha_EditField.Value  = 2;
    app.InitAngle_beta_EditField.Value   = 0; 
   
    % Get the state (d/dt)*[x,y,z,phi,theta,psi,alpha,beta] of the system 
    app.InitSpeed_x_EditField.Value  = 5;
    app.InitSpeed_y_EditField.Value  = 0;
    app.InitSpeed_z_EditField.Value  = 0;
    app.InitAngleSpeed_phi_EditField.Value     = 0;
    app.InitAngleSpeed_theta_EditField.Value   = 0;
    app.InitAngleSpeed_psi_EditField.Value     = 0;
    app.InitAngleSpeed_alpha_EditField.Value   = 0;
    app.InitAngleSpeed_beta_EditField.Value    = 0;

    %-----------SYSTEM TIME & INPUT SETTING-------------%
    % Get the Simulink time information
    app.SimTime_StepSize_EditField.Value  = 0.001;
    app.SimTime_StopTime_EditField.Value  = 1.8;

     
    % Set the lamp color to red to inform the user that unsaved changes exist
    app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];  
        end

        % Button pushed function: StyleBarrelRollTakeoffButton
        function StyleBarrelRollTakeoffButtonPushed(app, event)
    %-----------SYSTEM CONSTANT------------%
    % Get the mass & lengths of the system
    app.ConstMassOfDrone_EditField.Value       = 1.5;
    app.ConstMassOfPendulum_EditField.Value    = 0.65; 
    app.ConstLengthOfDroneArm_EditField.Value  = 2;
    app.ConstLengthOfPendulum_EditField.Value  = 1.2;

    %-----------MOTOR TORQUE & FORCE------------%
    % Get the input forces of the system 
    app.Force1_Slider.Value = 18;
    app.Force2_Slider.Value = 7;
    app.Force3_Slider.Value = 7;
    app.Force4_Slider.Value = 7;
    app.ValueMotor1_F_Label.Text = num2str(app.Force1_Slider.Value);
    app.ValueMotor2_F_Label.Text = num2str(app.Force2_Slider.Value);
    app.ValueMotor3_F_Label.Text = num2str(app.Force3_Slider.Value);
    app.ValueMotor4_F_Label.Text = num2str(app.Force4_Slider.Value);

    % Get the input torques of the system
    app.Torque1_Slider.Value = 5;
    app.Torque2_Slider.Value = 5;
    app.Torque3_Slider.Value = 5;
    app.Torque4_Slider.Value = 5;
    app.ValueMotor1_T_Label.Text = num2str(app.Torque1_Slider.Value);
    app.ValueMotor2_T_Label.Text = num2str(app.Torque2_Slider.Value);
    app.ValueMotor3_T_Label.Text = num2str(app.Torque3_Slider.Value);
    app.ValueMotor4_T_Label.Text = num2str(app.Torque4_Slider.Value);

    %-----------SYSTEM INITIAL CONDITION-------------%
    % Get the state [x,y,z,phi,theta,psi,alpha,beta] of the system 
    app.InitPosition_x_EditField.Value   = 0;
    app.InitPosition_y_EditField.Value   = 0;
    app.InitPosition_z_EditField.Value   = 3;
    app.InitAngle_phi_EditField.Value    = 0;
    app.InitAngle_theta_EditField.Value  = 0;
    app.InitAngle_psi_EditField.Value    = 0;
    app.InitAngle_alpha_EditField.Value  = 2;
    app.InitAngle_beta_EditField.Value   = 0; 
   
    % Get the state (d/dt)*[x,y,z,phi,theta,psi,alpha,beta] of the system 
    app.InitSpeed_x_EditField.Value  = 0;
    app.InitSpeed_y_EditField.Value  = -6;
    app.InitSpeed_z_EditField.Value  = 0;
    app.InitAngleSpeed_phi_EditField.Value     = 0;
    app.InitAngleSpeed_theta_EditField.Value   = 0;
    app.InitAngleSpeed_psi_EditField.Value     = 0;
    app.InitAngleSpeed_alpha_EditField.Value   = 0;
    app.InitAngleSpeed_beta_EditField.Value    = 0;

    %-----------SYSTEM TIME & INPUT SETTING-------------%
    % Get the Simulink time information
    app.SimTime_StepSize_EditField.Value  = 0.001;
    app.SimTime_StopTime_EditField.Value  = 0.98;

     
    % Set the lamp color to red to inform the user that unsaved changes exist
    app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];              
        end

        % Button pushed function: StyleFreeFallButton
        function StyleFreeFallButtonPushed(app, event)
    %-----------SYSTEM CONSTANT------------%
    % Get the mass & lengths of the system
    app.ConstMassOfDrone_EditField.Value       = 1.5;
    app.ConstMassOfPendulum_EditField.Value    = 0.65; 
    app.ConstLengthOfDroneArm_EditField.Value  = 2;
    app.ConstLengthOfPendulum_EditField.Value  = 1.2;

    %-----------MOTOR TORQUE & FORCE------------%
    % Get the input forces of the system 
    app.Force1_Slider.Value = 0;
    app.Force2_Slider.Value = 0;
    app.Force3_Slider.Value = 0;
    app.Force4_Slider.Value = 0;
    app.ValueMotor1_F_Label.Text = num2str(app.Force1_Slider.Value);
    app.ValueMotor2_F_Label.Text = num2str(app.Force2_Slider.Value);
    app.ValueMotor3_F_Label.Text = num2str(app.Force3_Slider.Value);
    app.ValueMotor4_F_Label.Text = num2str(app.Force4_Slider.Value);

    % Get the input torques of the system
    app.Torque1_Slider.Value = 0;
    app.Torque2_Slider.Value = 0;
    app.Torque3_Slider.Value = 0;
    app.Torque4_Slider.Value = 0;
    app.ValueMotor1_T_Label.Text = num2str(app.Torque1_Slider.Value);
    app.ValueMotor2_T_Label.Text = num2str(app.Torque2_Slider.Value);
    app.ValueMotor3_T_Label.Text = num2str(app.Torque3_Slider.Value);
    app.ValueMotor4_T_Label.Text = num2str(app.Torque4_Slider.Value);

    %-----------SYSTEM INITIAL CONDITION-------------%
    % Get the state [x,y,z,phi,theta,psi,alpha,beta] of the system 
    app.InitPosition_x_EditField.Value   = 0;
    app.InitPosition_y_EditField.Value   = 0;
    app.InitPosition_z_EditField.Value   = 5;
    app.InitAngle_phi_EditField.Value    = 0;
    app.InitAngle_theta_EditField.Value  = 0;
    app.InitAngle_psi_EditField.Value    = 0;
    app.InitAngle_alpha_EditField.Value  = 0;
    app.InitAngle_beta_EditField.Value   = 0; 
   
    % Get the state (d/dt)*[x,y,z,phi,theta,psi,alpha,beta] of the system 
    app.InitSpeed_x_EditField.Value  = 0;
    app.InitSpeed_y_EditField.Value  = 0;
    app.InitSpeed_z_EditField.Value  = 0;
    app.InitAngleSpeed_phi_EditField.Value     = 0;
    app.InitAngleSpeed_theta_EditField.Value   = 0;
    app.InitAngleSpeed_psi_EditField.Value     = 0;
    app.InitAngleSpeed_alpha_EditField.Value   = 0;
    app.InitAngleSpeed_beta_EditField.Value    = 0;

    %-----------SYSTEM TIME & INPUT SETTING-------------%
    % Get the Simulink time information
    app.SimTime_StepSize_EditField.Value  = 0.001;
    app.SimTime_StopTime_EditField.Value  = 10;

     
    % Set the lamp color to red to inform the user that unsaved changes exist
    app.UpdateIndicatorLamp.Color = [1.00,0.00,0.00];              
        end

        % Value changed function: PapersButton
        function PapersButtonValueChanged(app, event)
            open proje4_dronepend_paperV1.pdf;
            open proje4_dronepend_paperV2.pdf;      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DroneMotorDynamicsManagerPanel = uipanel(app.UIFigure);
            app.DroneMotorDynamicsManagerPanel.Title = '⚡Drone Motor Dynamics Manager⚡';
            app.Motor1_ParametersPanel = uipanel(app.DroneMotorDynamicsManagerPanel);
            app.Motor1_ParametersPanel.Title = 'Motor1_Parameters';
            app.Force_NLabel = uilabel(app.Motor1_ParametersPanel);
            app.Force_NLabel.Text = 'Force_N';
            app.Force1_Slider = uislider(app.Motor1_ParametersPanel);
            app.Force1_Slider.ValueChangingFcn = createCallbackFcn(app, @Force1_SliderValueChanging, true);
            app.Torque_NmLabel_2 = uilabel(app.Motor1_ParametersPanel);
            app.Torque_NmLabel_2.Text = 'Torque_Nm';
            app.Torque1_Slider = uislider(app.Motor1_ParametersPanel);
            app.Torque1_Slider.ValueChangingFcn = createCallbackFcn(app, @Torque1_SliderValueChanging, true);
            app.ValueMotor1_F_Label = uilabel(app.Motor1_ParametersPanel);
            app.ValueMotor1_F_Label.Text = 'Value:';
            app.ValueMotor1_T_Label = uilabel(app.Motor1_ParametersPanel);
            app.ValueMotor1_T_Label.Text = 'Value:';
            app.Motor2_ParametersPanel = uipanel(app.DroneMotorDynamicsManagerPanel);
            app.Motor2_ParametersPanel.Title = 'Motor2_Parameters';
            app.Force_NLabel_2 = uilabel(app.Motor2_ParametersPanel);
            app.Force_NLabel_2.Text = 'Force_N';
            app.Force2_Slider = uislider(app.Motor2_ParametersPanel);
            app.Force2_Slider.ValueChangingFcn = createCallbackFcn(app, @Force2_SliderValueChanging, true);
            app.Torque_NmLabel = uilabel(app.Motor2_ParametersPanel);
            app.Torque_NmLabel.Text = 'Torque_Nm';
            app.Torque2_Slider = uislider(app.Motor2_ParametersPanel);
            app.Torque2_Slider.ValueChangingFcn = createCallbackFcn(app, @Torque2_SliderValueChanging, true);
            app.ValueMotor2_F_Label = uilabel(app.Motor2_ParametersPanel);
            app.ValueMotor2_F_Label.Text = 'Value:';
            app.ValueMotor2_T_Label = uilabel(app.Motor2_ParametersPanel);
            app.ValueMotor2_T_Label.Text = 'Value:';
            app.Motor3_ParametersPanel = uipanel(app.DroneMotorDynamicsManagerPanel);
            app.Motor3_ParametersPanel.Title = 'Motor3_Parameters';
            app.Force_NLabel_3 = uilabel(app.Motor3_ParametersPanel);
            app.Force_NLabel_3.Text = 'Force_N';
            app.Force3_Slider = uislider(app.Motor3_ParametersPanel);
            app.Force3_Slider.ValueChangingFcn = createCallbackFcn(app, @Force3_SliderValueChanging, true);
            app.Torque_NmLabel_3 = uilabel(app.Motor3_ParametersPanel);
            app.Torque_NmLabel_3.Text = 'Torque_Nm';
            app.Torque3_Slider = uislider(app.Motor3_ParametersPanel);
            app.Torque3_Slider.ValueChangingFcn = createCallbackFcn(app, @Torque3_SliderValueChanging, true);
            app.ValueMotor3_F_Label = uilabel(app.Motor3_ParametersPanel);
            app.ValueMotor3_F_Label.Text = 'Value:';
            app.ValueMotor3_T_Label = uilabel(app.Motor3_ParametersPanel);
            app.ValueMotor3_T_Label.Text = 'Value:';
            app.Motor4_ParametersPanel = uipanel(app.DroneMotorDynamicsManagerPanel);
            app.Motor4_ParametersPanel.Title = 'Motor4_Parameters';
            app.Force_NLabel_4 = uilabel(app.Motor4_ParametersPanel);
            app.Force_NLabel_4.Text = 'Force_N';
            app.Force4_Slider = uislider(app.Motor4_ParametersPanel);
            app.Force4_Slider.ValueChangingFcn = createCallbackFcn(app, @Force4_SliderValueChanging, true);
            app.Torque_NmLabel_4 = uilabel(app.Motor4_ParametersPanel);
            app.Torque_NmLabel_4.Text = 'Torque_Nm';
            app.Torque4_Slider = uislider(app.Motor4_ParametersPanel);
            app.Torque4_Slider.ValueChangingFcn = createCallbackFcn(app, @Torque4_SliderValueChanging, true);
            app.ValueMotor4_F_Label = uilabel(app.Motor4_ParametersPanel);
            app.ValueMotor4_F_Label.Text = 'Value:';
            app.ValueMotor4_T_Label = uilabel(app.Motor4_ParametersPanel);
            app.ValueMotor4_T_Label.Text = 'Value:';
            app.Image6 = uiimage(app.DroneMotorDynamicsManagerPanel);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'GUI', 'Photos', 'pngwing.com (4)-Photoroom.png');
            app.VisualizationSettingsPanel = uipanel(app.UIFigure);
            app.VisualizationSettingsPanel.Title = '📈Visualization Settings📈';
            app.GraphicsSelectionScreenCheckBoxGroup = uibuttongroup(app.VisualizationSettingsPanel);
            app.GraphicsSelectionScreenCheckBoxGroup.Title = '            Graphics Selection Screen';
            app.GraphCheckBox_Pos_x = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_Pos_x.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_Pos_xValueChanged, true);
            app.GraphCheckBox_Pos_x.Text = 'Pos_x';
            app.GraphCheckBox_TransSpeed_x = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_TransSpeed_x.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_TransSpeed_xValueChanged, true);
            app.GraphCheckBox_TransSpeed_x.Text = 'Spd_x';
            app.GraphCheckBox_Pos_y = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_Pos_y.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_Pos_yValueChanged, true);
            app.GraphCheckBox_Pos_y.Text = 'Pos_y';
            app.GraphCheckBox_TransSpeed_y = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_TransSpeed_y.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_TransSpeed_yValueChanged, true);
            app.GraphCheckBox_TransSpeed_y.Text = 'Spd_y';
            app.GraphCheckBox_Pos_z = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_Pos_z.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_Pos_zValueChanged, true);
            app.GraphCheckBox_Pos_z.Text = 'Pos_z';
            app.GraphCheckBox_TransSpeed_z = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_TransSpeed_z.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_TransSpeed_zValueChanged, true);
            app.GraphCheckBox_TransSpeed_z.Text = 'Spd_z';
            app.GraphCheckBox_AnglePhi = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_AnglePhi.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_AnglePhiValueChanged, true);
            app.GraphCheckBox_AnglePhi.Text = 'Ang_Φ';
            app.GraphCheckBox_AngleSpeedPhi = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_AngleSpeedPhi.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_AngleSpeedPhiValueChanged, true);
            app.GraphCheckBox_AngleSpeedPhi.Text = 'AngSpd_Φ';
            app.GraphCheckBox_AngleTheta = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_AngleTheta.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_AngleThetaValueChanged, true);
            app.GraphCheckBox_AngleTheta.Text = 'Ang_Θ';
            app.GraphCheckBox_AngleSpeedTheta = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_AngleSpeedTheta.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_AngleSpeedThetaValueChanged, true);
            app.GraphCheckBox_AngleSpeedTheta.Text = 'AngSpd_Θ';
            app.GraphCheckBox_AnglePsi = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_AnglePsi.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_AnglePsiValueChanged, true);
            app.GraphCheckBox_AnglePsi.Text = 'Ang_Ψ';
            app.GraphCheckBox_AngleSpeedPsi = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_AngleSpeedPsi.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_AngleSpeedPsiValueChanged, true);
            app.GraphCheckBox_AngleSpeedPsi.Text = 'AngSpd_Ψ';
            app.GraphCheckBox_AngleAlpha = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_AngleAlpha.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_AngleAlphaValueChanged, true);
            app.GraphCheckBox_AngleAlpha.Text = 'Ang_α';
            app.GraphCheckBox_AngleSpeedAlpha = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_AngleSpeedAlpha.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_AngleSpeedAlphaValueChanged, true);
            app.GraphCheckBox_AngleSpeedAlpha.Text = 'AngSpd_α';
            app.GraphCheckBox_AngleBeta = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_AngleBeta.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_AngleBetaValueChanged, true);
            app.GraphCheckBox_AngleBeta.Text = 'Ang_β';
            app.GraphCheckBox_AngleSpeedBeta = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_AngleSpeedBeta.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_AngleSpeedBetaValueChanged, true);
            app.GraphCheckBox_AngleSpeedBeta.Text = 'AngSpd_β';
            app.LateralPositionandSpeedoftheQuadrotor_Label = uilabel(app.GraphicsSelectionScreenCheckBoxGroup);
            app.LateralPositionandSpeedoftheQuadrotor_Label.Text = 'Translational Position, Speed & Acceleration of Quadrotor';
            app.EulerPositionandSpeedoftheQuadrotor_Label = uilabel(app.GraphicsSelectionScreenCheckBoxGroup);
            app.EulerPositionandSpeedoftheQuadrotor_Label.Text = 'Euler Position, Speed & Acceleration of Quadrotor';
            app.AngleandSpeedofthePendulum_Label = uilabel(app.GraphicsSelectionScreenCheckBoxGroup);
            app.AngleandSpeedofthePendulum_Label.Text = 'Angle, Speed & Acceleration of the Pendulum';
            app.GraphCheckBox_TransAcc_x = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_TransAcc_x.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_TransAcc_xValueChanged, true);
            app.GraphCheckBox_TransAcc_x.Text = 'Acc_x';
            app.GraphCheckBox_TransAcc_y = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_TransAcc_y.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_TransAcc_yValueChanged, true);
            app.GraphCheckBox_TransAcc_y.Text = 'Acc_y';
            app.GraphCheckBox_TransAcc_z = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_TransAcc_z.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_TransAcc_zValueChanged, true);
            app.GraphCheckBox_TransAcc_z.Text = 'Acc_z';
            app.GraphCheckBox_AngleAccPhi = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_AngleAccPhi.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_AngleAccPhiValueChanged, true);
            app.GraphCheckBox_AngleAccPhi.Text = 'AngAcc_Φ';
            app.GraphCheckBox_AngleAccTheta = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_AngleAccTheta.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_AngleAccThetaValueChanged, true);
            app.GraphCheckBox_AngleAccTheta.Text = 'AngAcc_Θ';
            app.GraphCheckBox_AngleAccPsi = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_AngleAccPsi.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_AngleAccPsiValueChanged, true);
            app.GraphCheckBox_AngleAccPsi.Text = 'AngAcc_Ψ';
            app.GraphCheckBox_AngleAccAlpha = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_AngleAccAlpha.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_AngleAccAlphaValueChanged, true);
            app.GraphCheckBox_AngleAccAlpha.Text = 'AngAcc_α';
            app.GraphCheckBox_AngleAngAccBeta = uicheckbox(app.GraphicsSelectionScreenCheckBoxGroup);
            app.GraphCheckBox_AngleAngAccBeta.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBox_AngleAccBetaValueChanged, true);
            app.GraphCheckBox_AngleAngAccBeta.Text = 'AngAcc_β';
            app.Panel_5 = uipanel(app.VisualizationSettingsPanel);
            app.WARNINGTextAreaLabel = uilabel(app.Panel_5);
            app.WARNINGTextAreaLabel.Text = '⚠️ WARNING! :';
            app.WARNINGTextArea = uitextarea(app.Panel_5);
            app.WARNINGTextArea.Value = {'Simulation Units:'; ''; '• Angles           : deg(degree) (initials input value)'; '• Angles           : rad(radian)   (output graphics value) '; '• Positions        : m (meters)  '; '• Lengths          : m (meters)  '; '• Masses           : kg (kilograms)  '; '• Position Velocities : m/s (meters per second)  '; '• Angular Velocities   : rad/s (radians per second)  '; '• Force            : N (Newtons)  '; '• Torque           : N·m (Newton-meters)'; ''};
            app.Image4 = uiimage(app.Panel_5);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'GUI', 'Photos', 'klipartz.com.png');
            app.ReportButton = uibutton(app.Panel_5, 'push');
            app.ReportButton.Icon = fullfile(pathToMLAPP, 'GUI', 'Photos', 'report.png');
            app.ReportButton.Text = 'Report';
            app.Panel = uipanel(app.Panel_5);
            app.UpdateIndicatorLampLabel = uilabel(app.Panel);
            app.UpdateIndicatorLampLabel.Text = 'Update Indicator';
            app.UpdateIndicatorLamp = uilamp(app.Panel);
            app.PapersButton = uibutton(app.Panel_5, 'state');
            app.PapersButton.ValueChangedFcn = createCallbackFcn(app, @PapersButtonValueChanged, true);
            app.PapersButton.Icon = fullfile(pathToMLAPP, 'GUI', 'Photos', 'Makaleler.png');
            app.PapersButton.Text = 'Papers';
            app.Panel_6 = uipanel(app.VisualizationSettingsPanel);
            app.UIAxes = uiaxes(app.Panel_6);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationControlSettingTab = uitab(app.TabGroup);
            app.SimulationControlSettingTab.Title = 'Simulation Control & Setting';
            app.SimulationTimeSolverSettingsGuidePanel = uipanel(app.SimulationControlSettingTab);
            app.SimulationTimeSolverSettingsGuidePanel.Title = '🕹️Simulation Time & Solver Settings Guide🥽';
            app.SolverandTimingConfiguratorPanel = uipanel(app.SimulationTimeSolverSettingsGuidePanel);
            app.SolverandTimingConfiguratorPanel.Title = '🗝️Solver and Timing Configurator🕒';
            app.SolverandTimingConfiguratorPanel.ButtonDownFcn = createCallbackFcn(app, @SolverandTimingConfiguratorPanelButtonDown, true);
            app.StopTimeLabel = uilabel(app.SolverandTimingConfiguratorPanel);
            app.StopTimeLabel.Text = 'Stop Time';
            app.SimTime_StopTime_EditField = uieditfield(app.SolverandTimingConfiguratorPanel, 'numeric');
            app.StopTimeLabel_2 = uilabel(app.SolverandTimingConfiguratorPanel);
            app.StopTimeLabel_2.Text = 'Step Size';
            app.SimTime_StepSize_EditField = uieditfield(app.SolverandTimingConfiguratorPanel, 'numeric');
            app.sLabel = uilabel(app.SolverandTimingConfiguratorPanel);
            app.sLabel.Text = 's';
            app.sLabel_2 = uilabel(app.SolverandTimingConfiguratorPanel);
            app.sLabel_2.Text = 's';
            app.Panel_2 = uipanel(app.SolverandTimingConfiguratorPanel);
            app.VariableKnobLabel = uilabel(app.Panel_2);
            app.VariableKnobLabel.Text = 'Variable';
            app.SolverType_Variable_Knob = uiknob(app.Panel_2, 'discrete');
            app.SolverType_Variable_Knob.Items = {'auto', 'ode45', 'ode113', 'ode23'};
            app.SolverType_Variable_Knob.ValueChangedFcn = createCallbackFcn(app, @SolverType_Variable_KnobValueChanged, true);
            app.SolverType_Variable_Knob.Value = 'auto';
            app.Panel_3 = uipanel(app.SolverandTimingConfiguratorPanel);
            app.FixedKnobLabel = uilabel(app.Panel_3);
            app.FixedKnobLabel.Text = 'Fixed';
            app.SolverType_Fixed_Knob = uiknob(app.Panel_3, 'discrete');
            app.SolverType_Fixed_Knob.Items = {'auto', 'ode8', 'ode5', 'ode4', 'ode3', 'ode2'};
            app.SolverType_Fixed_Knob.ValueChangedFcn = createCallbackFcn(app, @SolverType_Fixed_KnobValueChanged, true);
            app.SolverType_Fixed_Knob.Value = 'auto';
            app.Panel_4 = uipanel(app.SolverandTimingConfiguratorPanel);
            app.SolverTypeSwitchLabel = uilabel(app.Panel_4);
            app.SolverTypeSwitchLabel.Text = 'SolverType';
            app.SolverType_Switch = uiswitch(app.Panel_4, 'rocker');
            app.SolverType_Switch.Items = {'Variable', 'Fixed'};
            app.SolverType_Switch.ValueChangedFcn = createCallbackFcn(app, @SolverType_SwitchValueChanged, true);
            app.SolverType_Switch.Value = 'Fixed';
            app.StartAndSolve_Simulink_Button = uibutton(app.SimulationTimeSolverSettingsGuidePanel, 'push');
            app.StartAndSolve_Simulink_Button.ButtonPushedFcn = createCallbackFcn(app, @StartAndSolve_Simulink_ButtonPushed, true);
            app.StartAndSolve_Simulink_Button.Icon = fullfile(pathToMLAPP, 'GUI', 'Photos', 'start-button.png');
            app.StartAndSolve_Simulink_Button.Text = 'Start The Simulation';
            app.SaveParam_GUI_Button = uibutton(app.SimulationTimeSolverSettingsGuidePanel, 'push');
            app.SaveParam_GUI_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveParam_GUI_ButtonPushed, true);
            app.SaveParam_GUI_Button.Icon = fullfile(pathToMLAPP, 'GUI', 'Photos', 'diskette.png');
            app.SaveParam_GUI_Button.Text = 'Save Parameters';
            app.Image5 = uiimage(app.SimulationControlSettingTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'GUI', 'Photos', 'pngwing.com (5).png');
            app.InitialConstantTab = uitab(app.TabGroup);
            app.InitialConstantTab.Title = 'Initial & Constant';
            app.DronePendulumMechanicalConstantsPanel = uipanel(app.InitialConstantTab);
            app.DronePendulumMechanicalConstantsPanel.Title = '🔩 Drone & Pendulum Mechanical Constants🔩';
            app.DronePendulumMechanicalConstantsPanel.ButtonDownFcn = createCallbackFcn(app, @DronePendulumMechanicalConstantsPanelButtonDown, true);
            app.MassofDroneEditFieldLabel = uilabel(app.DronePendulumMechanicalConstantsPanel);
            app.MassofDroneEditFieldLabel.Text = 'Mass of Drone';
            app.ConstMassOfDrone_EditField = uieditfield(app.DronePendulumMechanicalConstantsPanel, 'numeric');
            app.LengthofDroneArmEditFieldLabel = uilabel(app.DronePendulumMechanicalConstantsPanel);
            app.LengthofDroneArmEditFieldLabel.Text = 'Length of Drone Arm';
            app.ConstLengthOfDroneArm_EditField = uieditfield(app.DronePendulumMechanicalConstantsPanel, 'numeric');
            app.MassofPendulumEditFieldLabel = uilabel(app.DronePendulumMechanicalConstantsPanel);
            app.MassofPendulumEditFieldLabel.Text = 'Mass of Pendulum';
            app.ConstMassOfPendulum_EditField = uieditfield(app.DronePendulumMechanicalConstantsPanel, 'numeric');
            app.LengthofPendulumEditFieldLabel = uilabel(app.DronePendulumMechanicalConstantsPanel);
            app.LengthofPendulumEditFieldLabel.Text = 'Length of Pendulum';
            app.ConstLengthOfPendulum_EditField = uieditfield(app.DronePendulumMechanicalConstantsPanel, 'numeric');
            app.kgLabel = uilabel(app.DronePendulumMechanicalConstantsPanel);
            app.kgLabel.Text = 'kg';
            app.kgLabel_2 = uilabel(app.DronePendulumMechanicalConstantsPanel);
            app.kgLabel_2.Text = 'kg';
            app.mLabel_4 = uilabel(app.DronePendulumMechanicalConstantsPanel);
            app.mLabel_4.Text = 'm';
            app.mLabel_5 = uilabel(app.DronePendulumMechanicalConstantsPanel);
            app.mLabel_5.Text = 'm';
            app.InitialConditionsPanel = uipanel(app.InitialConstantTab);
            app.InitialConditionsPanel.Title = '🏁Initial Conditions🏁';
            app.InitialConditionsPanel.ButtonDownFcn = createCallbackFcn(app, @InitialConditionsPanelButtonDown, true);
            app.Position_xEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.Position_xEditFieldLabel.Text = 'Position_x';
            app.InitPosition_x_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.Position_yEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.Position_yEditFieldLabel.Text = 'Position_y';
            app.InitPosition_y_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.Position_zEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.Position_zEditFieldLabel.Text = 'Position_z';
            app.InitPosition_z_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.Angle_phiEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.Angle_phiEditFieldLabel.Text = 'Angle_phi';
            app.InitAngle_phi_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.Angle_thetaEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.Angle_thetaEditFieldLabel.Text = 'Angle_theta';
            app.InitAngle_theta_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.Angle_psiLabel = uilabel(app.InitialConditionsPanel);
            app.Angle_psiLabel.Text = 'Angle_psi';
            app.InitAngle_psi_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.Angle_alphaEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.Angle_alphaEditFieldLabel.Text = 'Angle_alpha';
            app.InitAngle_alpha_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.Angle_betaEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.Angle_betaEditFieldLabel.Text = 'Angle_beta';
            app.InitAngle_beta_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.Speed_xEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.Speed_xEditFieldLabel.Text = 'Speed_x';
            app.InitSpeed_x_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.Speed_yEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.Speed_yEditFieldLabel.Text = 'Speed_y';
            app.InitSpeed_y_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.Speed_zEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.Speed_zEditFieldLabel.Text = 'Speed_z';
            app.InitSpeed_z_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.AngleSpeed_phiEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.AngleSpeed_phiEditFieldLabel.Text = 'AngleSpeed_phi';
            app.InitAngleSpeed_phi_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.AngleSpeed_thetaEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.AngleSpeed_thetaEditFieldLabel.Text = 'AngleSpeed_theta';
            app.InitAngleSpeed_theta_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.AngleSpeed_psiLabel = uilabel(app.InitialConditionsPanel);
            app.AngleSpeed_psiLabel.Text = 'AngleSpeed_psi';
            app.InitAngleSpeed_psi_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.AngleSpeed_alphaEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.AngleSpeed_alphaEditFieldLabel.Text = 'AngleSpeed_alpha';
            app.InitAngleSpeed_alpha_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.AngleSpeed_betaEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.AngleSpeed_betaEditFieldLabel.Text = 'AngleSpeed_beta';
            app.InitAngleSpeed_beta_EditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.mLabel = uilabel(app.InitialConditionsPanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.InitialConditionsPanel);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.InitialConditionsPanel);
            app.mLabel_3.Text = 'm';
            app.degLabel = uilabel(app.InitialConditionsPanel);
            app.degLabel.Text = '°deg';
            app.degLabel_2 = uilabel(app.InitialConditionsPanel);
            app.degLabel_2.Text = '°deg';
            app.degLabel_3 = uilabel(app.InitialConditionsPanel);
            app.degLabel_3.Text = '°deg';
            app.degLabel_4 = uilabel(app.InitialConditionsPanel);
            app.degLabel_4.Text = '°deg';
            app.degLabel_5 = uilabel(app.InitialConditionsPanel);
            app.degLabel_5.Text = '°deg';
            app.msLabel = uilabel(app.InitialConditionsPanel);
            app.msLabel.Text = 'm/s';
            app.msLabel_2 = uilabel(app.InitialConditionsPanel);
            app.msLabel_2.Text = 'm/s';
            app.msLabel_3 = uilabel(app.InitialConditionsPanel);
            app.msLabel_3.Text = 'm/s';
            app.degsLabel = uilabel(app.InitialConditionsPanel);
            app.degsLabel.Text = '°deg/s';
            app.degsLabel_2 = uilabel(app.InitialConditionsPanel);
            app.degsLabel_2.Text = '°deg/s';
            app.degsLabel_3 = uilabel(app.InitialConditionsPanel);
            app.degsLabel_3.Text = '°deg/s';
            app.degsLabel_4 = uilabel(app.InitialConditionsPanel);
            app.degsLabel_4.Text = '°deg/s';
            app.degsLabel_5 = uilabel(app.InitialConditionsPanel);
            app.degsLabel_5.Text = '°deg/s';
            app.MotionStylesTab = uitab(app.TabGroup);
            app.MotionStylesTab.Title = 'Motion Styles';
            app.StyleHoverButton = uibutton(app.MotionStylesTab, 'push');
            app.StyleHoverButton.ButtonPushedFcn = createCallbackFcn(app, @StyleHoverButtonPushed, true);
            app.StyleHoverButton.Icon = fullfile(pathToMLAPP, 'GUI', 'Photos', '11-Photoroom.png');
            app.StyleHoverButton.Text = 'Style : Hover';
            app.StyleParabolicTakeoffButton = uibutton(app.MotionStylesTab, 'push');
            app.StyleParabolicTakeoffButton.ButtonPushedFcn = createCallbackFcn(app, @StyleParabolicTakeoffButtonPushed, true);
            app.StyleParabolicTakeoffButton.Icon = fullfile(pathToMLAPP, 'GUI', 'Photos', '33-Photoroom.png');
            app.StyleParabolicTakeoffButton.Text = 'Style : Parabolic Takeoff';
            app.StyleVortexTakeoffButton = uibutton(app.MotionStylesTab, 'push');
            app.StyleVortexTakeoffButton.ButtonPushedFcn = createCallbackFcn(app, @StyleVortexTakeoffButtonPushed, true);
            app.StyleVortexTakeoffButton.Icon = fullfile(pathToMLAPP, 'GUI', 'Photos', 'Ekran görüntüsü 2025-05-23 145143-Photoroom.png');
            app.StyleVortexTakeoffButton.Text = 'Style : Vortex Takeoff';
            app.StylePendulumKickButton = uibutton(app.MotionStylesTab, 'push');
            app.StylePendulumKickButton.ButtonPushedFcn = createCallbackFcn(app, @StylePendulumKickButtonPushed, true);
            app.StylePendulumKickButton.Icon = fullfile(pathToMLAPP, 'GUI', 'Photos', '22-Photoroom.png');
            app.StylePendulumKickButton.Text = 'Style :  Pendulum Kick';
            app.StyleBarrelRollTakeoffButton = uibutton(app.MotionStylesTab, 'push');
            app.StyleBarrelRollTakeoffButton.ButtonPushedFcn = createCallbackFcn(app, @StyleBarrelRollTakeoffButtonPushed, true);
            app.StyleBarrelRollTakeoffButton.Icon = fullfile(pathToMLAPP, 'GUI', 'Photos', '55-Photoroom.png');
            app.StyleBarrelRollTakeoffButton.Text = 'Style :Barrel Roll Takeoff';
            app.StyleFreeFallButton = uibutton(app.MotionStylesTab, 'push');
            app.StyleFreeFallButton.ButtonPushedFcn = createCallbackFcn(app, @StyleFreeFallButtonPushed, true);
            app.StyleFreeFallButton.Icon = fullfile(pathToMLAPP, 'GUI', 'Photos', '77-Photoroom.png');
            app.StyleFreeFallButton.Text = 'Style :  Free Fall';
            app.DronePendulumDiagramTab = uitab(app.TabGroup);
            app.DronePendulumDiagramTab.Title = 'Drone & Pendulum Diagram';
            app.Image3 = uiimage(app.DronePendulumDiagramTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'GUI', 'Photos', 'dronum.jpg');
            app.EulerAnglesTab = uitab(app.TabGroup);
            app.EulerAnglesTab.Title = 'Euler Angles';
            app.eulerAngles_2 = uiimage(app.EulerAnglesTab);
            app.eulerAngles_2.ImageSource = fullfile(pathToMLAPP, 'GUI', 'Photos', 'euler_3.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proje4__app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end